classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RiskAppetiteQuestionairePanel   matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        Question5TextAreaLabel          matlab.ui.control.Label
        WhatareyourreturnexpectationsforyourportfolioDropDownLabel  matlab.ui.control.Label
        WhatareyourreturnexpectationsforyourportfolioDropDown  matlab.ui.control.DropDown
        HowwouldyoucharacterizeyourpersonalityDropDownLabel  matlab.ui.control.Label
        HowwouldyoucharacterizeyourpersonalityDropDown  matlab.ui.control.DropDown
        WhenmonitoringwhatdoyouthinkyouwilltendtofocusonLabel  matlab.ui.control.Label
        YourChoiceDropDown              matlab.ui.control.DropDown
        WhichofthefollowingrisksoreventsdoyoufearmostDropDownLabel  matlab.ui.control.Label
        WhichofthefollowingrisksoreventsdoyoufearmostDropDown  matlab.ui.control.DropDown
        YourChoiceDropDown_5Label       matlab.ui.control.Label
        YourChoiceDropDown_2            matlab.ui.control.DropDown
        Question1TextAreaLabel          matlab.ui.control.Label
        Question1TextArea               matlab.ui.control.TextArea
        Question2TextAreaLabel          matlab.ui.control.Label
        Question2TextArea               matlab.ui.control.TextArea
        Question3TextAreaLabel          matlab.ui.control.Label
        Question3TextArea               matlab.ui.control.TextArea
        Question4TextAreaLabel          matlab.ui.control.Label
        Question4TextArea               matlab.ui.control.TextArea
        GeneratePorfolioButton          matlab.ui.control.Button
        Question5TextArea               matlab.ui.control.TextArea
        ResultEditFieldLabel            matlab.ui.control.Label
        ResultEditField                 matlab.ui.control.NumericEditField
        RiskLevelEditFieldLabel         matlab.ui.control.Label
        RiskLevelEditField              matlab.ui.control.EditField
        SubmitButton                    matlab.ui.control.Button
        RiskAppetiteQuestionaireButton  matlab.ui.control.Button
    end


    methods (Access = private)

        % Button pushed function: GeneratePorfolioButton
        function GeneratePorfolioButtonPushed(app, event)
            if app.RiskLevelEditField.Value == "Low"
                delete(app.UIFigure);
                app=Low;
            elseif app.RiskLevelEditField.Value == "Medium"
                delete(app.UIFigure);
                app=Medium;
            elseif app.RiskLevelEditField.Value == "NA"
                msgbox('Please finish questions first','Warning');
            else
                delete(app.UIFigure);
                app=High;  
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            score1=app.WhatareyourreturnexpectationsforyourportfolioDropDown.Value;
            score1=str2double(score1);
            if score1==0
                msgbox('Please finish question 1','Warning');
                return;
            end
            score2=app.HowwouldyoucharacterizeyourpersonalityDropDown.Value;
            score2=str2double(score2);
             if score2==0
                msgbox('Please finish question 2','Warning');
                return;
            end
            score3=app.YourChoiceDropDown.Value;
            score3=str2double(score3);
             if score3==0
                msgbox('Please finish question 3','Warning');
                return;
            end
            score4=app.WhichofthefollowingrisksoreventsdoyoufearmostDropDown.Value;
            score4=str2double(score4);
             if score4==0
                msgbox('Please finish question 4','Warning');
                return;
            end
            score5=app.YourChoiceDropDown_2.Value;
            score5=str2double(score5);
             if score5==0
                msgbox('Please finish question 5','Warning');
                return;
            end
            final=sum([score1,score2,score3,score4,score5]);
            app.ResultEditField.Value=final;
            if final <=10
                app.RiskLevelEditField.Value = 'Low';
            elseif final>=16
                app.RiskLevelEditField.Value = 'High';
            else
                app.RiskLevelEditField.Value = 'Medium';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RiskAppetiteQuestionairePanel = uipanel(app.UIFigure);
            app.RiskAppetiteQuestionairePanel.Title = 'Risk Appetite Questionaire';
            app.Label = uilabel(app.RiskAppetiteQuestionairePanel);
            app.Label.Text = ' ';
            app.Question5TextAreaLabel = uilabel(app.RiskAppetiteQuestionairePanel);
            app.Question5TextAreaLabel.Text = 'Question 5:';
            app.WhatareyourreturnexpectationsforyourportfolioDropDownLabel = uilabel(app.RiskAppetiteQuestionairePanel);
            app.WhatareyourreturnexpectationsforyourportfolioDropDownLabel.Text = 'What are your return expectations for your portfolio? ';
            app.WhatareyourreturnexpectationsforyourportfolioDropDown = uidropdown(app.RiskAppetiteQuestionairePanel);
            app.WhatareyourreturnexpectationsforyourportfolioDropDown.Items = {'A', 'B', 'C', 'D', 'NA'};
            app.WhatareyourreturnexpectationsforyourportfolioDropDown.ItemsData = {'1', '2', '3', '4', '0'};
            app.WhatareyourreturnexpectationsforyourportfolioDropDown.Value = '0';
            app.HowwouldyoucharacterizeyourpersonalityDropDownLabel = uilabel(app.RiskAppetiteQuestionairePanel);
            app.HowwouldyoucharacterizeyourpersonalityDropDownLabel.Text = 'How would you characterize your personality? ';
            app.HowwouldyoucharacterizeyourpersonalityDropDown = uidropdown(app.RiskAppetiteQuestionairePanel);
            app.HowwouldyoucharacterizeyourpersonalityDropDown.Items = {'A', 'B', 'C', 'D', 'NA'};
            app.HowwouldyoucharacterizeyourpersonalityDropDown.ItemsData = {'1', '2', '3', '4', '0'};
            app.HowwouldyoucharacterizeyourpersonalityDropDown.Value = '0';
            app.WhenmonitoringwhatdoyouthinkyouwilltendtofocusonLabel = uilabel(app.RiskAppetiteQuestionairePanel);
            app.WhenmonitoringwhatdoyouthinkyouwilltendtofocusonLabel.Text = 'When monitoring, what do you think you will tend to focus on?';
            app.YourChoiceDropDown = uidropdown(app.RiskAppetiteQuestionairePanel);
            app.YourChoiceDropDown.Items = {'A', 'B', 'C', 'D', 'NA'};
            app.YourChoiceDropDown.ItemsData = {'1', '2', '3', '4', '0'};
            app.YourChoiceDropDown.Value = '0';
            app.WhichofthefollowingrisksoreventsdoyoufearmostDropDownLabel = uilabel(app.RiskAppetiteQuestionairePanel);
            app.WhichofthefollowingrisksoreventsdoyoufearmostDropDownLabel.Text = 'Which of the following risks or events do you fear most? ';
            app.WhichofthefollowingrisksoreventsdoyoufearmostDropDown = uidropdown(app.RiskAppetiteQuestionairePanel);
            app.WhichofthefollowingrisksoreventsdoyoufearmostDropDown.Items = {'A', 'B', 'C', 'D', 'NA'};
            app.WhichofthefollowingrisksoreventsdoyoufearmostDropDown.ItemsData = {'1', '2', '3', '4', '0'};
            app.WhichofthefollowingrisksoreventsdoyoufearmostDropDown.Value = '0';
            app.YourChoiceDropDown_5Label = uilabel(app.RiskAppetiteQuestionairePanel);
            app.YourChoiceDropDown_5Label.Text = 'If the value of investment portfolio dropped by 20% in one year, what would you do? ';
            app.YourChoiceDropDown_2 = uidropdown(app.RiskAppetiteQuestionairePanel);
            app.YourChoiceDropDown_2.Items = {'A', 'B', 'C', 'D', 'NA'};
            app.YourChoiceDropDown_2.ItemsData = {'1', '2', '3', '4', '0'};
            app.YourChoiceDropDown_2.Value = '0';
            app.Question1TextAreaLabel = uilabel(app.RiskAppetiteQuestionairePanel);
            app.Question1TextAreaLabel.Text = 'Question 1:';
            app.Question1TextArea = uitextarea(app.RiskAppetiteQuestionairePanel);
            app.Question1TextArea.Value = {'a) I don’t care if my portfolio keeps pace with inflation; I just want to preserve my capital. '; 'b) My return should keep pace with inflation, with minimum volatility. '; 'c) My return should be slightly more than inflation, with only moderate volatility. '; 'd) My return should significantly exceed inflation, even if this could mean significant volatility.'};
            app.Question2TextAreaLabel = uilabel(app.RiskAppetiteQuestionairePanel);
            app.Question2TextAreaLabel.Text = 'Question 2:';
            app.Question2TextArea = uitextarea(app.RiskAppetiteQuestionairePanel);
            app.Question2TextArea.Value = {'a) I’m a pessimist. I always expect the worst. '; 'b) I’m anxious. No matter what you say, I’ll worry. '; 'c) I’m cautious but open to new ideas. Convince me. '; 'd) I’m objective. Show me the pros and cons and I can make a decision and live with it. '; 'e) I’m optimistic. Things always work out in the end.'};
            app.Question3TextAreaLabel = uilabel(app.RiskAppetiteQuestionairePanel);
            app.Question3TextAreaLabel.Text = 'Question 3:';
            app.Question3TextArea = uitextarea(app.RiskAppetiteQuestionairePanel);
            app.Question3TextArea.Value = {'a) Individual investments that are doing poorly. '; 'b) Individual investments that are doing very well. '; 'c) The recent results of my overall portfolio. '; 'd) The long term performance of my overall portfolio.'};
            app.Question4TextAreaLabel = uilabel(app.RiskAppetiteQuestionairePanel);
            app.Question4TextAreaLabel.Text = 'Question 4:';
            app.Question4TextArea = uitextarea(app.RiskAppetiteQuestionairePanel);
            app.Question4TextArea.Value = {'a) A loss of principal over any period of 1 year or less. '; 'b) A rate of inflation that exceeds my rate of return over the long term, because it will erode the purchasing power of my money. '; 'c) Portfolio performance that is insufficient to meet my goals. '; 'd) Portfolio performance that is consistently less than industry benchmarks. '; 'e) A missed investment opportunity that could have yielded higher returns over the long term, even though it entailed higher risk.'; ''; ''};
            app.GeneratePorfolioButton = uibutton(app.RiskAppetiteQuestionairePanel, 'push');
            app.GeneratePorfolioButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePorfolioButtonPushed, true);
            app.GeneratePorfolioButton.Text = 'Generate Porfolio';
            app.Question5TextArea = uitextarea(app.RiskAppetiteQuestionairePanel);
            app.Question5TextArea.Value = {'a) Fire my investment advisor. '; 'b) Move my money to more conservative investments immediately to reduce the potential for future losses. '; 'c) Monitor the situation, and if it looks like things could continue to deteriorate, move some of my money to more conservative investments. '; 'd) Consult with my investment advisor to ensure that my asset allocation is correct, and then ride it out. '; 'e) Consider investing more because prices are so low.'};
            app.ResultEditFieldLabel = uilabel(app.RiskAppetiteQuestionairePanel);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.RiskAppetiteQuestionairePanel, 'numeric');
            app.ResultEditField.LowerLimitInclusive = 'off';
            app.ResultEditField.UpperLimitInclusive = 'off';
            app.RiskLevelEditFieldLabel = uilabel(app.RiskAppetiteQuestionairePanel);
            app.RiskLevelEditFieldLabel.Text = 'Risk Level';
            app.RiskLevelEditField = uieditfield(app.RiskAppetiteQuestionairePanel, 'text');
            app.RiskLevelEditField.Value = 'NA';
            app.SubmitButton = uibutton(app.RiskAppetiteQuestionairePanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.RiskAppetiteQuestionaireButton = uibutton(app.UIFigure, 'push');
            app.RiskAppetiteQuestionaireButton.Text = 'Risk Appetite Questionaire';
        end
    end

    methods (Access = public)

        % Construct app
        function app = test

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end